classdef TrainFinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        arrivalTimeField           matlab.ui.control.EditField
        ArrivalTimeEditFieldLabel  matlab.ui.control.Label
        title                      matlab.ui.control.Label
        DateField                  matlab.ui.control.DatePicker
        DateLabel                  matlab.ui.control.Label
        SearchTrainButton          matlab.ui.control.Button
        ArrivalText                matlab.ui.control.Label
        DepartureField             matlab.ui.control.DropDown
        FromDropDownLabel          matlab.ui.control.Label
        plot                       matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                 
        % -------------------------------------------------------------
        %                   CODE FROM EXERCISE 2
        % -------------------------------------------------------------
            
            addpath('data_import');                                                     % Add paths of the input files
            file_id         = fopen('cologne_munich.txt', 'r');                         % Load train information with read access
            traindata       = textscan(file_id, '%u%s%s%f%f%s%f%f%.2f%.2f', 'delimiter', ',');  % Read and store traintdata
            
            %% Store specific data from the file in local variables
            index           = traindata{:,1};
            trainNumber     = traindata{:,2};
            departTime_h    = traindata{:,4};
            departTime_m    = traindata{:,5};
            arrivalTime_h   = traindata{:,7};
            arrivalTime_m   = traindata{:,8};
            budgetPrice     = traindata{:,9};
            normalPrice     = traindata{:,10};
            
            %% Calculate the travel time of each train
            departTime      = departTime_h + (departTime_m/60);
            arrivalTime     = arrivalTime_h + (arrivalTime_m/60);
            
            travelTime      = arrivalTime - departTime;
            
        % -------------------------------------------------------------
        %                   ADAPT PLOT COMMANDS TO GUI
        % -------------------------------------------------------------
            
            %% Subplot 1 - Budget-Price and Normal-Price over Departure Time
            plot(app.plot, departTime, budgetPrice, 'b-')
            hold(app.plot, 'on')
            plot(app.plot, departTime, normalPrice, 'r-')
            
            % Use GUI syntax to optimize plot
            app.plot.Title.String = 'Price Development over Time of Day';
            app.plot.XLabel.String = 'Time of Day [h]';
            app.plot.YLabel.String = 'Price [Euro]';  
            legend(app.plot, 'Budget Price', 'Normal Price')
            grid(app.plot, 'on')         
            
        end

        % Button pushed function: SearchTrainButton
        function SearchTrainButtonPushed(app, event)
        
        % -------------------------------------------------------------
        %          CALL NESTED FUNCTION FROM EXERCISE 1
        % -------------------------------------------------------------  
            
            departStation = app.DepartureField.Value;
            arrivalStation = 'München Hbf';
            
            GetNextTrain(departStation, arrivalStation);   
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.plot = uiaxes(app.UIFigure);
            title(app.plot, 'Title')
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.FromDropDownLabel = uilabel(app.UIFigure);
            app.FromDropDownLabel.Text = 'From:';
            app.DepartureField = uidropdown(app.UIFigure);
            app.DepartureField.Items = {'Hamburg-Altona', 'Berlin Gesundbrunnen', 'Dortmund Hbf'};
            app.DepartureField.Value = 'Hamburg-Altona';
            app.ArrivalText = uilabel(app.UIFigure);
            app.ArrivalText.Text = 'To:       Munich Central Station';
            app.SearchTrainButton = uibutton(app.UIFigure, 'push');
            app.SearchTrainButton.ButtonPushedFcn = createCallbackFcn(app, @SearchTrainButtonPushed, true);
            app.SearchTrainButton.Text = 'Search Train';
            app.DateLabel = uilabel(app.UIFigure);
            app.DateLabel.Text = 'Date:';
            app.DateField = uidatepicker(app.UIFigure);
            app.DateField.DisplayFormat = 'uuuu-MM-dd';
            app.title = uilabel(app.UIFigure);
            app.title.Text = 'Train Finder';
            app.ArrivalTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ArrivalTimeEditFieldLabel.Text = 'Arrival Time:';
            app.arrivalTimeField = uieditfield(app.UIFigure, 'text');
            app.arrivalTimeField.Tooltip = {''};
            app.arrivalTimeField.Value = '08:00';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrainFinder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end